classdef proEye < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UploadyourphotoLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        BrowseButton          matlab.ui.control.Button
        Label_2               matlab.ui.control.Label
        ParametersLabel       matlab.ui.control.Label
        SaturationLabel       matlab.ui.control.Label
        HueLabel              matlab.ui.control.Label
        FaceRatioLabel        matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        CheckImageButton      matlab.ui.control.Button
        proEyebyteammLabel    matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,path,indx] = uigetfile('*.jpg');
            if isequal(file,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', fullfile(path, file),...
                    ' and filter index: ', num2str(indx)])
                app.EditField.Value = fullfile(path, file);
                disp(fullfile(path, file));
            end
        end

        % Button pushed function: CheckImageButton
        function CheckImageButtonPushed(app, event)
            
            imgR = imread(app.EditField.Value);
            disp(imgR);
            [flag1,faceAns]=checkFace(imgR);
           
            [imag1,BB]=upperCrop(imgR);
            [imag2,BB2]=faceCrop(imag1);
            faceRemoved=removeFace(imag1,BB2);
            [flag2,coverageAns]=coverage(imag1,imag2);
            app.Label_5.Text = coverageAns;
            f=removeForeground(faceRemoved,BB,BB2);
            [flag3,flag4,saturationAns,hueAns]=hsv(f);
            app.Label_3.Text = saturationAns;
            app.Label_4.Text = hueAns;
            if flag1 && flag2 && flag3 && flag4
                app.Label_2.Text="Congratulations! Image is Valid";
            else
                app.Label_2.Text="Try Again! Image is Not Valid";
            end    
            %
            
            function [imag1,BB]=upperCrop(imgR)
                [imag1,BB]=upperbody(imgR);
                figure(4);
                imshow(imag1);
            end
            
            function [imag2,BB2]=faceCrop(imag1)
                [imag2, BB2]=face(imag1);
                figure(5);
                imshow(imag2);
            end
            function faceRemoved=removeFace(imag1,BB2)
                faceRemoved = imag1;
                faceRemoved(BB2(2):BB2(2)+BB2(4), BB2(1):BB2(1)+BB2(3),:) = 0;
                figure(6);
                imshow(faceRemoved);
            end
            
            
            function f=removeForeground(faceRemoved,BB,BB2)
                f=faceRemoved;
                f(BB2(3)+BB2(2):BB(3),1:BB(3),:) = 0;
                figure(7);
                imshow(f);
            end
            
            
            function [flag3,flag4,saturationAns,hueAns]=hsv(f)
                newI=rgb2hsv(f);
                H=newI(:,:,1);
                H= round(H,1);
                S=newI(:,:,2);
                [flag3,saturationAns]=saturation(S);
                [flag4,hueAns]=hue(H);
            end
            
            
            function [flag1,answer]=hue(H)
                totalBracket1=sum(sum((0.1972>H&H>0)));
                totalBracket2=sum(sum((0.3916>H&H>0.1972)));
                totalBracket3=sum(sum((0.5916>H&H>0.3916)));
                totalBracket4=sum(sum((0.7888>H&H>0.5916)));
                totalBracket5=sum(sum((0.9972>H&H>0.7888)));
                TotalHues=totalBracket1+totalBracket2+totalBracket3+totalBracket4+totalBracket5;
                M=[totalBracket1,totalBracket2,totalBracket3,totalBracket4,totalBracket5];
                Maximum=max(M);
                index=find(M==Maximum);
                M(index) = 0;
                SecondMax=max(M);
                diff=Maximum-SecondMax;
                if diff/TotalHues < 0.7
                    answer="Fail Hue";
                    flag1=false;
                    %disp(final);
                else
                    flag1=true;
                    answer="Pass Hue";
                end
            end
            
            function [flag1,answer]=saturation(S)
                totalFound=sum(sum((S>0.6)));
                totalOverall=sum(sum((S>0)));
                percentS=totalFound/totalOverall;
                if percentS > 0.05
                    flag1=false;
                    answer="Fail Saturation";
                    %disp(final);
                else
                    flag1=true;
                    answer="Pass Saturation";
                end
            end
            
            function [flag1,answer]=coverage(imag1,imag2)
                sizeBigImage=size(imag1);
                overalPixels=sizeBigImage(1)*sizeBigImage(2);
                sizeFaceImage=size(imag2);
                FacePixels=sizeFaceImage(1)*sizeFaceImage(2);
                TotalRemovedPixels=FacePixels*4;
                PercentageRemoved=TotalRemovedPixels/overalPixels;
                if  PercentageRemoved < 0.5
                    flag1=false;
                    answer="Fail Coverage";
                    %disp(final);
                else
                    flag1=true;
                    answer="Pass Coverage";
                end
            end
            
            function [x, BB2]=face(imag1)
                img = imag1;
                FaceDetect = vision.CascadeObjectDetector;
                FaceDetect.MergeThreshold = 7 ;
                BB2 = step(FaceDetect,img);
                for i = 1:size(BB2,1)
                    rectangle('Position',BB2(i,:),'LineWidth',3,'LineStyle','-','EdgeColor','r');
                end
                for i = 1:size(BB2,1)
                    J= imcrop(img,BB2(i,:));
                end
                x=J;
            end
            
            
            function [f,BB]=upperbody(img)
                checkFace(img);
                FaceDetect = vision.CascadeObjectDetector('UpperBody');
                FaceDetect.MergeThreshold = 7 ;
                BB = step(FaceDetect,img);
                for i = 1:size(BB,1)
                    rectangle('Position',BB(i,:),'LineWidth',3,'LineStyle','-','EdgeColor','r');
                end
                for i = 1:size(BB,1)
                    J= imcrop(img,BB(i,:));
                end
                f=J;
            end


            function [flag1,x]=checkFace(img)
                I=img;
                FDetect = vision.CascadeObjectDetector;
                %Returns Bounding Box values based on number of objects
                BB = step(FDetect,I);
                % % hold on
                for i = 1:size(BB,1)
                    rectangle('Position',BB(i,:),'LineWidth',5,'LineStyle','-','EdgeColor','r');
                end
                % title('Face Detection');
                % hold off;
                
                %To detect Nose
                NoseDetect = vision.CascadeObjectDetector('Nose','MergeThreshold',16);
                BB1=step(NoseDetect,I);
                
                for i = 1:size(BB,1)
                    rectangle('Position',BB(i,:),'LineWidth',4,'LineStyle','-','EdgeColor','b');
                end
                % title('Nose Detection');
                % hold off;
                
                %To detect Mouth
                MouthDetect = vision.CascadeObjectDetector('Mouth','MergeThreshold',16);
                
                BB2=step(MouthDetect,I);
                
                for i = 1:size(BB,1)
                    rectangle('Position',BB(i,:),'LineWidth',4,'LineStyle','-','EdgeColor','r');
                end
                % title('Mouth Detection');
                % hold off;
                
                %Check if the image has a face
                if isempty(BB) || isempty(BB1) || isempty(BB2)
                    flag1=false;
                    x = 'Fail Check Face';
                    %disp(x);
                else
                    flag1=true;
                    x = 'Pass Check Face';
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UploadyourphotoLabel = uilabel(app.UIFigure);
            app.UploadyourphotoLabel.Text = 'Upload your photo';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.SaturationLabel = uilabel(app.UIFigure);
            app.SaturationLabel.Text = 'Saturation:';
            app.HueLabel = uilabel(app.UIFigure);
            app.HueLabel.Text = 'Hue:';
            app.FaceRatioLabel = uilabel(app.UIFigure);
            app.FaceRatioLabel.Text = 'Face Ratio:';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.CheckImageButton = uibutton(app.UIFigure, 'push');
            app.CheckImageButton.ButtonPushedFcn = createCallbackFcn(app, @CheckImageButtonPushed, true);
            app.CheckImageButton.Text = 'Check Image';
            app.proEyebyteammLabel = uilabel(app.UIFigure);
            app.proEyebyteammLabel.Text = {'proEye '; '          -by team ''.m'''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = proEye

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end